classdef Motor_GUI_NML < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        LoadingDataTab              matlab.ui.container.Tab
        FilterandExtractPanel       matlab.ui.container.Panel
        ExtractSwitch               matlab.ui.control.RockerSwitch
        ExtractSwitchLabel          matlab.ui.control.Label
        SpatialFilterKnob           matlab.ui.control.DiscreteKnob
        SpatialFilterKnobLabel      matlab.ui.control.Label
        LoadingPanel                matlab.ui.container.Panel
        StatusTextArea              matlab.ui.control.TextArea
        StatusTextAreaLabel         matlab.ui.control.Label
        LoadDataButton              matlab.ui.control.Button
        MotorGUINeuroMechatronicsLabLabel  matlab.ui.control.Label
        p2pandRMSAnalysisofMUAPTab  matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        ShowResultButton            matlab.ui.control.Button
        AnalysisDropDown            matlab.ui.control.DropDown
        AnalysisDropDownLabel       matlab.ui.control.Label
        p2pSwitch                   matlab.ui.control.Switch
        p2pSwitchLabel              matlab.ui.control.Label
        RMSSwitch                   matlab.ui.control.Switch
        RMSSwitchLabel              matlab.ui.control.Label
        MUDropDown                  matlab.ui.control.DropDown
        MUDropDownLabel             matlab.ui.control.Label
        MotorGUINeuroMechatronicsLabLabel_4  matlab.ui.control.Label
        minp2pSwitch                matlab.ui.control.Switch
        minp2pSwitchLabel           matlab.ui.control.Label
        Avgp2pSwitch                matlab.ui.control.Switch
        Avgp2pSwitchLabel           matlab.ui.control.Label
        Maxp2pSwitch                matlab.ui.control.Switch
        Maxp2pSwitchLabel           matlab.ui.control.Label
        ThresholdCalculationsTab    matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        RecruitmentTab              matlab.ui.container.Tab
        ShowResultButton_2          matlab.ui.control.Button
        EditField_endTime           matlab.ui.control.NumericEditField
        EditField_startTime         matlab.ui.control.NumericEditField
        EditField_numFiring         matlab.ui.control.NumericEditField
        EndTimesKnob                matlab.ui.control.Knob
        EndTimesKnobLabel           matlab.ui.control.Label
        StartTimesKnob              matlab.ui.control.Knob
        StartTimesKnobLabel         matlab.ui.control.Label
        MotorGUINeuroMechatronicsLabLabel_5  matlab.ui.control.Label
        NumberofFiringsKnob         matlab.ui.control.Knob
        NumberofFiringsKnobLabel    matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        DerecruitmentTab            matlab.ui.container.Tab
        ShowResultButton_3          matlab.ui.control.Button
        EndTimesKnob_2              matlab.ui.control.Knob
        EndTimesKnob_2Label         matlab.ui.control.Label
        StartTimesKnob_2            matlab.ui.control.Knob
        StartTimesKnob_2Label       matlab.ui.control.Label
        NumberofFiringsKnob_2       matlab.ui.control.Knob
        NumberofFiringsKnob_2Label  matlab.ui.control.Label
        EditField_endTime_2         matlab.ui.control.NumericEditField
        EditField_startTime_2       matlab.ui.control.NumericEditField
        EditField_numFiring_2       matlab.ui.control.NumericEditField
        MotorGUINeuroMechatronicsLabLabel_6  matlab.ui.control.Label
        UIAxes2_2                   matlab.ui.control.UIAxes
        CVInvestigatorTab           matlab.ui.container.Tab
        TabGroup3                   matlab.ui.container.TabGroup
        AutoCVTab                   matlab.ui.container.Tab
        UITable2                    matlab.ui.control.Table
        MotorGUINeuroMechatronicsLabLabel_7  matlab.ui.control.Label
        AutoCVSwitch                matlab.ui.control.RockerSwitch
        AutoCVSwitch_2Label         matlab.ui.control.Label
        ManualInvestigtorTab        matlab.ui.container.Tab
        EndRowSpinner               matlab.ui.control.Spinner
        EndRowSpinnerLabel          matlab.ui.control.Label
        StartRowSpinner             matlab.ui.control.Spinner
        StartRowSpinnerLabel        matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        MotorUnitDropDown           matlab.ui.control.DropDown
        MotorUnitDropDownLabel      matlab.ui.control.Label
        SelectedColumnSpinner       matlab.ui.control.Spinner
        SelectedColumnLabel         matlab.ui.control.Label
        MotorGUINeuroMechatronicsLabLabel_8  matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        PlotSignalTab               matlab.ui.container.Tab
        MotorGUINeuroMechatronicsLabLabel_2  matlab.ui.control.Label
        ChooseSignalDropDown        matlab.ui.control.DropDown
        ChooseSignalDropDownLabel   matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        PlotMUAPTab                 matlab.ui.container.Tab
        ChooseMUAPDropDown          matlab.ui.control.DropDown
        ChooseMUAPDropDownLabel     matlab.ui.control.Label
        MotorGUINeuroMechatronicsLabLabel_3  matlab.ui.control.Label
        UIAxes_2                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file, path, idx] = uigetfile;
            if isequal(file, 0) || isequal(path, 0)
                % Append "File selection canceled" message to StatusTextArea
                app.StatusTextArea.Value = [app.StatusTextArea.Value; {'File selection canceled.'}];
            else
                filename = fullfile(path, file);
                % Append the selected file name to StatusTextArea
                app.StatusTextArea.Value = [app.StatusTextArea.Value; {['Selected file: ', filename]}];
                app.StatusTextArea.Value = [app.StatusTextArea.Value; {' '}];
                
                % Load the data
                try
                    App_import_data = importdata(filename);
                    
                    % Unpack the struct into the workspace
                    if isstruct(App_import_data)
                        fields = fieldnames(App_import_data);
                        for i = 1:numel(fields)
                            fieldname = fields{i};
                            assignin('base', fieldname, App_import_data.(fieldname)); % Assign each field to workspace
                        end
                        assignin('base', 'App_import_data', App_import_data); % Assign the whole data to the workspace
                        
                        % Append success message to StatusTextArea
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Data loaded successfully.'}];
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; {' '}];
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Data fields unpacked successfully.'}];
                    else
                        assignin('base', 'App_import_data', App_import_data); % Assign the whole data to the workspace
                        % Append success message to StatusTextArea
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Data loaded successfully.'}];
                    end
                catch ME
                    % Append error message to StatusTextArea
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; {['Error loading data: ', ME.message]}];
                end
            end
        end

        % Value changed function: SpatialFilterKnob
        function SpatialFilterKnobValueChanged(app, event)
            knobValue = app.SpatialFilterKnob.Value;  % Get the current value of the knob
            fsamp = 4000; % Example sampling frequency, replace with your actual value
        
            % Load SIG from the workspace
            SIG = evalin('base', 'SIG');  % Load SIG from the MATLAB base workspace
            newSig = evalin('base','SIG'); % initialize newSIG
        
            % Filter the signal with a lowpass and highpass filter
            for r = 1:size(SIG, 1)
                for c = 1:size(SIG, 2)
                    try
                        SIG{r, c} = lpbutter(SIG{r, c}, 500, fsamp);
                        SIG{r, c} = hpbutter(SIG{r, c}, 20, fsamp);
                    catch
                        % Handle filtering errors silently
                    end
                end
            end
        
            % Flip the signal matrix
            flipSIG = SIG; 
        
            % Handle knob actions
            switch knobValue
                case 'Off'
                    % If the knob is set to "Off", delete newSig from the workspace
                    try
                        evalin('base', 'clear newSig');  % Clear the newSig variable from the workspace
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Knob is Off, newSig variable deleted. Assigning SIG to newSig'}];
                        assignin('base', 'newSig', SIG);
                    catch
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Knob is Off, newSig variable not found. Assigning SIG to newSig.'}];
                        assignin('base', 'newSig', SIG);
                    end

                case 'Default'
                    assignin('base', 'newSig', SIG);
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Making use of Default Signal.'}];
                case 'SD Row'
                    % Compute single differential across rows
                    newSigRowDiff = cell(size(flipSIG, 1) - 1, size(flipSIG, 2));
                    for r = 1:size(flipSIG, 1) - 1
                        for c = 1:size(flipSIG, 2)
                            if ~isempty(flipSIG{r, c}) && ~isempty(flipSIG{r + 1, c})
                                newSigRowDiff{r, c} = flipSIG{r, c} - flipSIG{r + 1, c};
                            end
                        end
                    end
                    assignin('base', 'newSig', newSigRowDiff);  % Store the result in the workspace
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Computed SD Row.'}];
        
                case 'SD Column'
                    % Compute single differential across columns
                    newSigColDiff = cell(size(flipSIG, 1), size(flipSIG, 2) - 1);
                    for r = 1:size(flipSIG, 1)
                        for c = 1:size(flipSIG, 2) - 1
                            if ~isempty(flipSIG{r, c}) && ~isempty(flipSIG{r, c + 1})
                                newSigColDiff{r, c} = flipSIG{r, c} - flipSIG{r, c + 1};
                            end
                        end
                    end
                    assignin('base', 'newSig', newSigColDiff);  % Store the result in the workspace
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Computed SD Column.'}];
        
                case 'Laplacian'
                    % Compute Laplacian (differentials across both rows and columns)
                    newSigRowDiff = cell(size(flipSIG, 1) - 1, size(flipSIG, 2));
                    for r = 1:size(flipSIG, 1) - 1
                        for c = 1:size(flipSIG, 2)
                            if ~isempty(flipSIG{r, c}) && ~isempty(flipSIG{r + 1, c})
                                newSigRowDiff{r, c} = flipSIG{r, c} - flipSIG{r + 1, c};
                            end
                        end
                    end
        
                    newSigLaplacian = cell(size(newSigRowDiff, 1), size(newSigRowDiff, 2) - 1);
                    for r = 1:size(newSigRowDiff, 1)
                        for c = 1:size(newSigRowDiff, 2) - 1
                            if ~isempty(newSigRowDiff{r, c}) && ~isempty(newSigRowDiff{r, c + 1})
                                newSigLaplacian{r, c} = newSigRowDiff{r, c} - newSigRowDiff{r, c + 1};
                            end
                        end
                    end
                    assignin('base', 'newSig', newSigLaplacian);  % Store the result in the workspace
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Computed Laplacian.'}];
        
            end
        end

        % Value changed function: ChooseSignalDropDown
        function ChooseSignalDropDownValueChanged(app, event)
            selectedSignal = app.ChooseSignalDropDown.Value; % Get the selected signal from the dropdown
            % Load the selected signal from the workspace
            if strcmp(selectedSignal, 'SIG')
                signalData = evalin('base', 'SIG');  
            elseif strcmp(selectedSignal, 'newSig')
                signalData = evalin('base', 'newSig');  

            elseif strcmp(selectedSignal, 'Choose option below')
                app.StatusTextArea.Value = [app.StatusTextArea.Value; {"Select Valid option"}];
            end

            function plotEMGSignal(app, signalMatrix, selectedSignal)
                % Clear the current plot
                cla(app.UIAxes);
            
                % Number of rows and columns (electrodes)
                [nRows, nCols] = size(signalMatrix);
            
                % Generate time axis
                timeLength = length(signalMatrix{1, 1});
                timeAxis = (0:timeLength - 1) / 4000;  % Assuming a 4 kHz sampling rate
            
                % Create a y-axis offset for each electrode
                yStep = 1000;  % Step between each electrode plot
                yOffsets = yStep * (0:nRows * nCols - 1);  % Array of y-offsets for each electrode
            
                hold(app.UIAxes, 'on');  

                % Store labels and y-tick positions
                electrodeLabels = {};
                yTickPositions = [];

                signalCounter = 1;
            
                for r = 1:nRows
                    for c = 1:nCols
                        if ~isempty(signalMatrix{r, c})
                            % Plot the signal with y-offset
                            plot(app.UIAxes, timeAxis, signalMatrix{r, c} + yOffsets(signalCounter), 'DisplayName', sprintf('R%dC%d', r, c));
                            
                            % Store the label and y position
                            electrodeLabels{end + 1} = sprintf('R%dC%d', r, c);
                            yTickPositions(end + 1) = yOffsets(signalCounter);
            
                            signalCounter = signalCounter + 1;
                        end
                    end
                end
            
                ylim(app.UIAxes, [-yStep, yOffsets(end) + yStep]);
                xlim(app.UIAxes, [0, max(timeAxis)]);
            
                % Set y-ticks and labels
                yticks(app.UIAxes, yTickPositions); 
                yticklabels(app.UIAxes, electrodeLabels);  
            
                % Set plot labels and grid
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Electrodes');
                title(app.UIAxes, sprintf('EMG Signal - %s', selectedSignal));
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
            end
            % Plot the signal
            plotEMGSignal(app, signalData, selectedSignal);
            app.StatusTextArea.Value = [app.StatusTextArea.Value; sprintf('EMG Signal - %s', selectedSignal)];
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ChooseMUAPDropDown
        function ChooseMUAPDropDownValueChanged(app, event)
            selectedMUAP = app.ChooseMUAPDropDown.Value;

            % Load the selected signal from the workspace
            if strcmp(selectedMUAP, 'MUPulses')
                MUAPData = evalin('base', 'MUPulses'); 
                cla(app.UIAxes_2);
                hold(app.UIAxes_2, 'on'); 
                plotRaster(app, MUAPData);  % Call the function to plot raster
                hold(app.UIAxes_2, 'off'); 
                app.StatusTextArea.Value = [app.StatusTextArea.Value; sprintf('MUAP Signal - %s', selectedMUAP)];
            elseif strcmp(selectedMUAP, 'Force')
                % Load the reference signal (force data)
                force = evalin('base', 'ref_signal(1, :)'); 
                cla(app.UIAxes_2);
                hold(app.UIAxes_2, 'on'); 
                plotForce(app, force);  % Call the function to plot the force signal
                hold(app.UIAxes_2, 'off'); 
                app.StatusTextArea.Value = [app.StatusTextArea.Value; sprintf('Force Signal - %s', selectedMUAP)];
            elseif strcmp(selectedMUAP, 'Plot All')
                MUAPData = evalin('base', 'MUPulses'); 
                hold(app.UIAxes_2, 'on'); 
                h1 = plotRaster(app, MUAPData);  % Call the function to plot raster and get handles
                
                force = evalin('base', 'ref_signal(1, :)');  
                h2 = plotForce(app, force);  % Call the function to plot the force signal and get handles
                
                hold(app.UIAxes_2, 'off'); 
                
                % Fetch MUIDs from the base workspace directly for legend
                MUIDs = evalin('base', 'MUIDs');  
                
                % Combine the handles and create a joint legend
                legend([h1; h2], [MUIDs, {'Force'}], 'Location', 'northeastoutside');
                
                app.StatusTextArea.Value = [app.StatusTextArea.Value; sprintf('Plot MUAP Signal & Force Signal')];
            end
        
            % Function to plot the raster
            function h = plotRaster(app, MUPulses)
                % Number of motor units
                force = evalin('base', 'ref_signal(1, :)');  % force
                forceMax = max(force);
                nUnits = length(MUPulses);
                yStep = forceMax / (nUnits + 1);  % Adjust spacing relative to the force plot's max
                yOffsets = yStep * (1:nUnits);  % Y position for each motor unit
                colors = lines(nUnits);  % Get distinct colors for each unit
                fsamp = evalin('base', 'fsamp');  % Sampling frequency
        
                % Fetch MUIDs from the base workspace
                MUIDs = evalin('base', 'MUIDs');
                
                % Prepare an array to store line objects for the legend
                h = gobjects(nUnits, 1);
                
                % Loop through each motor unit
                for unitIdx = 1:nUnits
                    if ~isempty(MUPulses{unitIdx})
                        % Get the AP onset times for this motor unit
                        onsetTimes = MUPulses{unitIdx} / fsamp;
        
                        % Plot each AP as a vertical line at the onset times with shorter lines
                        for t = 1:length(onsetTimes)
                            x = onsetTimes(t);  % Time of AP
                            h(unitIdx) = plot(app.UIAxes_2, [x x], [yOffsets(unitIdx) yOffsets(unitIdx) + yStep / 3], ...  % Line is shorter
                                'Color', colors(unitIdx, :), 'LineWidth', 1);  % Adjust width if needed
                        end
                    end
                end
        
                % Set custom y-axis ticks and labels using MUIDs
                yticks(app.UIAxes_2, yOffsets);  % Set the ticks at the correct offsets
                yticklabels(app.UIAxes_2, MUIDs);  % Use the provided MUIDs for labeling
        
                % Set y-axis limits based on the force plot's max value
                ylim(app.UIAxes_2, [0, forceMax]);  % Align the raster plot with the force plot's max
        
                % Set plot labels and grid
                xlabel(app.UIAxes_2, 'Time (s)');
                ylabel(app.UIAxes_2, 'Motor Units');
                title(app.UIAxes_2, 'Motor Unit Raster Plot');
                grid(app.UIAxes_2, 'on');
            end
        
            % Function to plot the force signal
            function h = plotForce(app, force)
                % Load the sampling frequency
                fsamp = evalin('base', 'fsamp');
                
                % Create a time vector for the force data
                time = (0:length(force)-1) / fsamp;
                
                % Plot the force data in red
                h = plot(app.UIAxes_2, time, force, 'r-', 'LineWidth', 1.5);
                
                % Set the y-axis tick labels to be the force values
                yticks(app.UIAxes_2, linspace(min(force), max(force), 10));  % Set 10 evenly spaced ticks
                yticklabels(app.UIAxes_2, num2str(linspace(min(force), max(force), 10)', '%.2f'));  % Format labels to 2 decimal places
                
                % Set plot labels and title
                xlabel(app.UIAxes_2, 'Time (s)');
                ylabel(app.UIAxes_2, 'Force (a.u.)');
                title(app.UIAxes_2, 'Force Signal');
                
                % Enable grid
                grid(app.UIAxes_2, 'on');            
            end
        end

        % Value changed function: ExtractSwitch
        function ExtractSwitchValueChanged(app, event)
            value = app.ExtractSwitch.Value;
            if strcmp(value, 'Extract MUAPs')
                % Fetch MUPulses and MUIDs from the base workspace
                MUPulses = evalin('base', 'MUPulses');
                MUIDs = evalin('base', 'MUIDs');
                newSig = evalin('base', 'newSig');  % Also fetch the signal data (newSig) from the base workspace
                fsamp = evalin('base', 'fsamp');    % Fetch sampling rate from workspace
        
                % Ensure MUPulses is a cell array
                if isnumeric(MUPulses)
                    MUPulses = num2cell(MUPulses);
                end
        
                % Remove empty cells
                emptyCells = cellfun(@isempty, MUPulses);
                MUPulses(emptyCells) = [];
                MUIDs(emptyCells) = [];
        
                % Collect MUAPs
                MUAPs = {};
                MUAPrms = {};
                MUAPp2p = {};
                MU_ID = {};
        
                for m = 1:length(MUPulses)
                    % Extract MUAP data using the extractMUAPs_Luigi function
                    [MUAPs{end + 1}, MUAPrms{end + 1}, MUAPp2p{end + 1}, ~] = extractMUAPs_Luigi(newSig, MUPulses{m}, fsamp);
        
                    if length(MUIDs{m}) < 10
                        MU_ID{end + 1} = MUIDs{m};
                    else
                        MU_ID{end + 1} = 'MUID error';
                    end
                end
        
                % Assign calculated data to the base workspace
                assignin('base', 'MUAPs', MUAPs);
                assignin('base', 'MUAPrms', MUAPrms);
                assignin('base', 'MUAPp2p', MUAPp2p);
                assignin('base', 'MU_ID', MU_ID);
                assignin('base', 'MUPulses', MUPulses);
                assignin('base', 'MUIDs', MUIDs);
        
                % Ensure MUIDs is a column vector
                MUIDs = MUIDs(:);


                % Initialize cv_manual, recruitment_force, derecruitment_force, cv_auto, and cv_final with MU names and NaN
                cv_manual = [MUIDs, num2cell(NaN(length(MUIDs), 1))];
                recruitment_force = [MUIDs, num2cell(NaN(length(MUIDs), 1))];
                derecruitment_force = [MUIDs, num2cell(NaN(length(MUIDs), 1))];
                cv_auto = [MUIDs, num2cell(NaN(length(MUIDs), 1))];
                cv_final = [MUIDs, num2cell(NaN(length(MUIDs), 1))];
    
                % Reset analysis results
                p2pResults = {};
                maxP2pResults = [];
                avgP2pResults = [];
                minP2pResults = [];
                rmsResults = {};
        
                % Update StatusTextArea in the app to indicate processing is complete
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "MUAP data processing complete."];
        
            elseif strcmp(value, 'Off')
                % Clear the processed data from the base workspace when switched off
                evalin('base', 'clear MUAPs MUAPrms MUAPp2p MU_ID');
                
                % Update StatusTextArea to indicate the data has been cleared
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "MUAPs MUAPrms MUAPp2p MU_ID data cleared from workspace."];
            end
        end

        % Value changed function: p2pSwitch
        function p2pSwitchValueChanged(app, event)
            value = app.p2pSwitch.Value;
            if strcmp(value, 'On')
                % Initialize and store the p2p results in the base workspace
                p2pResults = evalin('base', 'MUAPp2p');  % Fetch MUAPp2p from the base workspace
                assignin('base', 'p2pResults', p2pResults);  % Store in base workspace
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "p2p Results calculated and stored."];
            else
                % Clear p2p results if switch is off
                evalin('base', 'clear p2pResults');
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "p2p Results cleared."];
            end
        end

        % Value changed function: Maxp2pSwitch
        function Maxp2pSwitchValueChanged(app, event)
            value = app.Maxp2pSwitch.Value;
            if strcmp(value, 'On')
                % Calculate and store the max p2p results in the base workspace
                MUAPp2p = evalin('base', 'MUAPp2p');  % Fetch MUAPp2p from base workspace
                maxP2pResults = cellfun(@(x) max(x(:)), MUAPp2p);  % Calculate max
                assignin('base', 'maxP2pResults', maxP2pResults);  % Store in base workspace
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Max p2p Results calculated and stored."];
            else
                % Clear max p2p results if switch is off
                evalin('base', 'clear maxP2pResults');
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Max p2p Results cleared."];
            end
        end

        % Value changed function: Avgp2pSwitch
        function Avgp2pSwitchValueChanged(app, event)
            value = app.Avgp2pSwitch.Value;
            if strcmp(value, 'On')
                % Calculate and store the average p2p results in the base workspace
                MUAPp2p = evalin('base', 'MUAPp2p');  % Fetch MUAPp2p from base workspace
                avgP2pResults = cellfun(@(x) mean(x(:)), MUAPp2p);  % Calculate mean
                assignin('base', 'avgP2pResults', avgP2pResults);  % Store in base workspace
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Average p2p Results calculated and stored."];
            else
                % Clear average p2p results if switch is off
                evalin('base', 'clear avgP2pResults');
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Average p2p Results cleared."];
            end
        end

        % Value changed function: minp2pSwitch
        function minp2pSwitchValueChanged(app, event)
            value = app.minp2pSwitch.Value;
            if strcmp(value, 'On')
                % Calculate and store the minimum p2p results in the base workspace
                MUAPp2p = evalin('base', 'MUAPp2p');  % Fetch MUAPp2p from base workspace
                minP2pResults = cellfun(@(x) min(x(:)), MUAPp2p);  % Calculate minimum
                assignin('base', 'minP2pResults', minP2pResults);  % Store in base workspace
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Min p2p Results calculated and stored."];
            else
                % Clear minimum p2p results if switch is off
                evalin('base', 'clear minP2pResults');
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Min p2p Results cleared."];
            end
        end

        % Value changed function: RMSSwitch
        function RMSSwitchValueChanged(app, event)
            value = app.RMSSwitch.Value;
            if strcmp(value, 'On')
                % Fetch and store the RMS results in the base workspace
                rmsResults = evalin('base', 'MUAPrms');  % Fetch MUAPrms from base workspace
                assignin('base', 'rmsResults', rmsResults);  % Store in base workspace
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "RMS Results calculated and stored."];
            else
                % Clear RMS results if switch is off
                evalin('base', 'clear rmsResults');
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "RMS Results cleared."];
            end
        end

        % Value changed function: MUDropDown, MotorUnitDropDown
        function MUDropDownValueChanged(app, event)
            value = app.MUDropDown.Value;
            % Get the selected MU ID from the dropdown
            selectedMU = app.MUDropDown.Value;
            assignin('base', 'selectedMU', selectedMU); % Store the selected MU in the base workspace for later use 
            app.StatusTextArea.Value = [app.StatusTextArea.Value; sprintf('Selected MU: %s', selectedMU)];% Display the selected MU in the status area
        end

        % Drop down opening function: MUDropDown, MotorUnitDropDown
        function MUDropDownOpening(app, event)
            % Fetch MU_IDs from the base workspace and update the dropdown
            MU_ID = evalin('base', 'MU_ID');
            app.MUDropDown.Items = MU_ID;
        end

        % Value changed function: AnalysisDropDown
        function AnalysisDropDownValueChanged(app, event)
            % Get the selected analysis type from the dropdown
            selectedAnalysis = app.AnalysisDropDown.Value;
            
            % Store the selected analysis type in the base workspace
            assignin('base', 'selectedAnalysis', selectedAnalysis);
            
            % Display the selected analysis in the status area
            app.StatusTextArea.Value = [app.StatusTextArea.Value; sprintf('Selected Analysis: %s', selectedAnalysis)];
        end

        % Drop down opening function: AnalysisDropDown
        function AnalysisDropDownOpening(app, event)
            % Define the available analysis types
            analysisOptions = {'p2p', 'Max p2p', 'Avg p2p', 'Min p2p', 'RMS'};
            app.AnalysisDropDown.Items = analysisOptions;
        end

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: ShowResultButton
        function ShowResultButtonPushed(app, event)
            % Fetch the selected Motor Unit (MU) from the dropdown
            selectedMU = app.MUDropDown.Value; % This is the string, e.g., 'MU1'
            
            % Fetch the selected analysis type from the dropdown
            selectedAnalysis = app.AnalysisDropDown.Value; % This is the analysis type string
            
            % Fetch data from base workspace (these remain unchanged)
            p2pResults = evalin('base', 'p2pResults');
            maxP2pResults = evalin('base', 'maxP2pResults');
            avgP2pResults = evalin('base', 'avgP2pResults');
            minP2pResults = evalin('base', 'minP2pResults');
            rmsResults = evalin('base', 'rmsResults');
            MU_ID = evalin('base', 'MU_ID'); % Get the list of MU_IDs
            
            % Find the index of the selectedMU in MU_ID
            selectedMUIndex = find(strcmp(MU_ID, selectedMU), 1); % Find the index of the selected MU
            
            % Check if the selectedMU is valid
            if isempty(selectedMUIndex)
                errordlg('Selected Motor Unit not found.', 'Error');
                return;
            end
            
            % Initialize the table data
            tableData = [];
            
            % Retrieve the selected result based on the dropdown selections
            switch selectedAnalysis
                case 'p2p'
                    if ~isempty(p2pResults)
                        tableData = p2pResults{selectedMUIndex}; % Use the index, not the string
                    end
                case 'Max p2p'
                    if ~isempty(maxP2pResults)
                        tableData = maxP2pResults(selectedMUIndex);
                    end
                case 'Avg p2p'
                    if ~isempty(avgP2pResults)
                        tableData = avgP2pResults(selectedMUIndex);
                    end
                case 'Min p2p'
                    if ~isempty(minP2pResults)
                        tableData = minP2pResults(selectedMUIndex);
                    end
                case 'RMS'
                    if ~isempty(rmsResults)
                        tableData = rmsResults{selectedMUIndex};
                    end
            end
            
            % Check if the tableData is empty
            if isempty(tableData)
                errordlg('No data available for the selected Motor Unit and analysis.', 'No Data');
                return;
            end
            
            % Prepare the data for display in the UITable
            if isnumeric(tableData) && isvector(tableData)
                % Convert row vector to column vector if necessary
                if isrow(tableData)
                    tableData = tableData';
                end
                tableData = num2cell(tableData); % Convert numeric array to cell array
            elseif isnumeric(tableData) && ismatrix(tableData)
                % Convert numeric matrix to cell array
                tableData = num2cell(tableData);
            else
                errordlg('Unsupported data type for display.', 'Error');
                return;
            end
            
            % Set the data to the app.UITable
            app.UITable.Data = tableData;
        
            % Set column and row names if needed
            numColumns = size(tableData, 2);
            columnName = arrayfun(@(i) num2str(i), 1:numColumns, 'UniformOutput', false); % Simple numeric column names
            app.UITable.ColumnName = columnName;
            numRows = size(tableData, 1);
            rowName = arrayfun(@(i) num2str(i), 1:numRows, 'UniformOutput', false); % Simple numeric column names
            app.UITable.RowName = rowName;
        end

        % Value changed function: EditField_numFiring
        function EditField_numFiringValueChanged(app, event)
            
            numFiring = round(app.EditField_numFiring.Value);  % Round to nearest integer
            app.EditField_numFiring.Value = numFiring;         % Ensure value in field is integer
         

            app.NumberofFiringsKnob.Value = round(numFiring);
            assignin('base','numFiring', round(numFiring));
        end

        % Value changed function: EditField_startTime
        function EditField_startTimeValueChanged(app, event)
            startTime = app.EditField_startTime.Value;
            app.EditField_startTime.Value = round(startTime);

            app.StartTimesKnob.Value = round(startTime);
            assignin('base','startTime', round(startTime));  % Store the start time
        end

        % Value changed function: EditField_endTime
        function EditField_endTimeValueChanged(app, event)
            endTime = round(app.EditField_endTime.Value);
            app.EditField_endTime.Value = round(endTime);
            
            app.EndTimesKnob.Value = round(endTime);
            assignin('base','endTime', round(endTime));  % Store the end time
        end

        % Button pushed function: ShowResultButton_2
        function ShowResultButton_2Pushed(app, event)
            function calculateRecruitmentThresholdCallback(app)
                % Get user inputs from the app
                n = round(evalin('base', 'numFiring'));  % Number of firings to consider
                start_time = round(evalin('base', 'startTime'));  % Start time of the analysis window
                end_time = round(evalin('base', 'endTime'));  % End time of the analysis window
                fsamp = evalin('base','fsamp');
            
                % Ensure data is loaded
                MUPulses = evalin('base', 'MUPulses');
                force = evalin('base', 'ref_signal(1,:)');
                assignin('base','Time',(0:size(force, 2) - 1) / fsamp);
                time = evalin('base', 'Time');
                fsamp = evalin('base', 'fsamp');
                
                if isempty(MUPulses) || isempty(force)
                    errordlg('Please load data first.');
                    return;
                end
            
                % If start_time and end_time are not provided, use the full signal
                if isnan(start_time)
                    start_time = 0;
                end
                if isnan(end_time)
                    end_time = max(time);
                end
            
                % Filter the time and force data to the specified window
                time_window_idx = (time >= start_time) & (time <= end_time);
                time_window = time(time_window_idx);
                force_window = force(time_window_idx);
            
                % Calculate the mean force for the first n firings of each motor unit
                numMUs = length(MUPulses); % Number of motor units
                mean_recruitment_forces = zeros(numMUs, 1);
                for i = 1:numMUs
                    firing_times = MUPulses{i} / fsamp; % Convert sample indices to time
                    firing_times_in_window = firing_times((firing_times >= start_time) & (firing_times <= end_time));
            
                    if length(firing_times_in_window) >= n
                        % Recruitment Force (First n Firings)
                        first_n_firing_times = firing_times_in_window(1:n); % Get the times of the first n firings
                        forces_at_first_n_firings = zeros(n, 1);
                        for j = 1:n
                            [~, idx] = min(abs(time_window - first_n_firing_times(j))); % Find the closest time index in the force data
                            forces_at_first_n_firings(j) = force_window(idx); % Get the force at each firing time
                        end
                        mean_recruitment_forces(i) = mean(forces_at_first_n_firings); % Calculate mean force
                    else
                        mean_recruitment_forces(i) = Inf; % If less than n firings, set to Inf to be ordered last
                    end
                end
            
                % Determine the order of motor units based on the mean recruitment forces
                [~, sorted_indices] = sort(mean_recruitment_forces);
            
                % Plot Motor Unit Firing Times and Force Curve in the order of recruitment on app.UIAxes2
                cla(app.UIAxes2);  % Clear the existing plot
                hold(app.UIAxes2, 'on');
                title(app.UIAxes2, 'Motor Unit Firing Times and Force Curve in Order of Recruitment');
                xlabel(app.UIAxes2, 'Time (s)');
                ylabel(app.UIAxes2, 'Force');
                grid(app.UIAxes2, 'on');
            
                colors = lines(numMUs); % Generate distinct colors for each motor unit
            
                for rank = 1:numMUs
                    i = sorted_indices(rank);
                    firing_times = MUPulses{i} / fsamp; % Convert sample indices to time
                    firing_times_in_window = firing_times((firing_times >= start_time) & (firing_times <= end_time));
                    y = repmat(rank, size(firing_times_in_window)); % Create a y-vector for the current MU
                    plot(app.UIAxes2, firing_times_in_window, y, '|', 'MarkerSize', 10, 'Color', colors(i, :), 'DisplayName', sprintf('MU %d', i)); % Plot firing times as vertical lines
                end
            
                % Plot the force curve
                force_plot = plot(app.UIAxes2, time_window, force_window, 'r', 'LineWidth', 2, 'DisplayName', 'Force'); 
                yticks(app.UIAxes2, 1:numMUs);
                yticklabels(app.UIAxes2, arrayfun(@(x) sprintf('MU %d', x), sorted_indices, 'UniformOutput', false));
                xlim(app.UIAxes2, [start_time, end_time]); % Adjust the x-axis limits based on the interval
                legend(app.UIAxes2, 'show');
                hold(app.UIAxes2, 'off');
            end
            calculateRecruitmentThresholdCallback(app);  % Call the recruitment threshold function
            app.StatusTextArea.Value = [app.StatusTextArea.Value; "Calculated Recruitment Threshold"];

        end

        % Value changing function: NumberofFiringsKnob
        function NumberofFiringsKnobValueChanging(app, event)
            numFiring = round(event.Value);
            app.NumberofFiringsKnob.Value = round(numFiring);
            app.EditField_numFiring.Value = round(numFiring); % Update edit field in real-time
            assignin('base', 'numFiring', round(numFiring)); % Store value in workspace
        end

        % Value changing function: StartTimesKnob
        function StartTimesKnobValueChanging(app, event)
            startTime = round(event.Value);
            app.StartTimesKnob.Value = round(startTime);
            app.EditField_startTime.Value = round(startTime); % Update edit field in real-time
            assignin('base', 'startTime', round(startTime)); % Store value in workspace
        end

        % Value changing function: EndTimesKnob
        function EndTimesKnobValueChanging(app, event)
            endTime = round(event.Value);
            
            app.EndTimesKnob.Value = round(endTime);
            app.EditField_endTime.Value = round(endTime); % Update edit field in real-time
            assignin('base', 'endTime', round(endTime)); % Store value in workspace
        end

        % Value changing function: NumberofFiringsKnob_2
        function NumberofFiringsKnob_2ValueChanging(app, event)
            numFiring = round(event.Value);
            app.NumberofFiringsKnob_2.Value = round(numFiring);
            app.EditField_numFiring_2.Value = round(numFiring); % Update edit field in real-time
            assignin('base', 'numFiring', round(numFiring));
        end

        % Value changing function: StartTimesKnob_2
        function StartTimesKnob_2ValueChanging(app, event)
            startTime = round(event.Value);
            app.StartTimesKnob_2.Value = round(startTime);
            app.EditField_startTime_2.Value = round(startTime); % Update edit field in real-time
            assignin('base', 'startTime', round(startTime));
        end

        % Value changing function: EndTimesKnob_2
        function EndTimesKnob_2ValueChanging(app, event)
            endTime = round(event.Value);
            app.EndTimesKnob_2.Value = round(endTime);
            app.EditField_endTime_2.Value = round(endTime); % Update edit field in real-time
            assignin('base', 'endTime', round(endTime)); 
            
        end

        % Value changed function: EditField_numFiring_2
        function EditField_numFiring_2ValueChanged(app, event)

            numFiring = round(app.EditField_numFiring_2.Value);  % Round to nearest integer
            app.EditField_numFiring_2.Value = numFiring;         % Ensure value in field is integer
            app.NumberofFiringsKnob_2.Value = round(numFiring);
            assignin('base','numFiring', round(numFiring));
            
        end

        % Value changed function: EditField_startTime_2
        function EditField_startTime_2ValueChanged(app, event)
   
            startTime = app.EditField_startTime_2.Value;
            app.EditField_startTime_2.Value = round(startTime);

            app.StartTimesKnob_2.Value = round(startTime);
            assignin('base','startTime', round(startTime)); 
        end

        % Value changed function: EditField_endTime_2
        function EditField_endTime_2ValueChanged(app, event)
        
            endTime = round(app.EditField_endTime_2.Value);
            app.EditField_endTime_2.Value = round(endTime);
            
            app.EndTimesKnob_2.Value = round(endTime);
            assignin('base','endTime', round(endTime)); 
        end

        % Button pushed function: ShowResultButton_3
        function ShowResultButton_3Pushed(app, event)
            function calculateDerecruitmentThresholdCallback(app)
                % Get user inputs from the app
                n = round(evalin('base', 'numFiring'));  % Number of firings to consider
                start_time = round(evalin('base', 'startTime'));  % Start time of the analysis window
                end_time = round(evalin('base', 'endTime'));  % End time of the analysis window
                fsamp = evalin('base','fsamp');
            
                % Ensure data is loaded
                MU_ID = evalin('base','MU_ID');
                MUPulses = evalin('base', 'MUPulses');
                force = evalin('base', 'ref_signal(1,:)');
                assignin('base','Time',(0:size(force, 2) - 1) / fsamp);
                time = evalin('base', 'Time');
                fsamp = evalin('base', 'fsamp');
                
                if isempty(MUPulses) || isempty(force)
                    errordlg('Please load data first.');
                    return;
                end
            
                % If start_time and end_time are not provided, use the full signal
                if isnan(start_time)
                    start_time = 0;
                end
                if isnan(end_time)
                    end_time = max(time);
                end
            
                % Filter the time and force data to the specified window
                time_window_idx = (time >= start_time) & (time <= end_time);
                time_window = time(time_window_idx);
                force_window = force(time_window_idx);
            
                % Calculate the mean force for the first n firings of each motor unit
                numMUs = length(MUPulses); % Number of motor units
                mean_derecruitment_forces = zeros(numMUs, 1);
                for i = 1:numMUs
                    firing_times = MUPulses{i} / fsamp; % Convert sample indices to time
                    firing_times_in_window = firing_times((firing_times >= start_time) & (firing_times <= end_time));
            
                    if length(firing_times_in_window) >= n
                        % Derecruitment Force (First n Firings)
                        last_n_firing_times = firing_times_in_window(end-n+1:end);
                        forces_at_last_n_firings = arrayfun(@(ft) force_window(find(time_window >= ft, 1)), last_n_firing_times);
                        mean_derecruitment_forces(i) = mean(forces_at_last_n_firings);
 
                    else
                        mean_derecruitment_forces(i) = Inf; % If less than n firings, set to Inf to be ordered last
                    end
                end
            
                % Determine the order of motor units based on the mean derecruitment forces
                [~, sorted_indices] = sort(mean_derecruitment_forces);

                sorted_table_data = [(1:numMUs)', mean_derecruitment_forces];
                sorted_table_data = sorted_table_data(sorted_indices, :);
            
                % Plot Motor Unit Firing Times and Force Curve in the order of derecruitment on app.UIAxes2_2
                cla(app.UIAxes2_2);  % Clear the existing plot
                hold(app.UIAxes2_2, 'on');
                title(app.UIAxes2_2, 'Motor Unit Firing Times and Force Curve in Order of Derecruitment');
                xlabel(app.UIAxes2_2, 'Time (s)');
                ylabel(app.UIAxes2_2, 'Force');
                grid(app.UIAxes2_2, 'on');
            
                colors = lines(numMUs); % Generate distinct colors for each motor unit
            
                for rank = 1:numMUs
                    i = sorted_indices(rank);
                    firing_times = MUPulses{i} / fsamp; % Convert sample indices to time
                    firing_times_in_window = firing_times((firing_times >= start_time) & (firing_times <= end_time));
                    y = repmat(rank, size(firing_times_in_window)); % Create a y-vector for the current MU
                    plot(app.UIAxes2_2, firing_times_in_window, y, '|', 'MarkerSize', 10, 'Color', colors(i, :), 'DisplayName', sprintf('MU %d', i)); % Plot firing times as vertical lines
                end
            
                % Plot the force curve
                force_plot = plot(app.UIAxes2_2, time_window, force_window, 'r', 'LineWidth', 2, 'DisplayName', 'Force'); 
                yticks(app.UIAxes2_2, 1:numMUs);
                yticklabels(app.UIAxes2_2, arrayfun(@(x) sprintf('MU %d', x), sorted_indices, 'UniformOutput', false));
                xlim(app.UIAxes2_2, [start_time, end_time]); % Adjust the x-axis limits based on the interval
                legend(app.UIAxes2_2, 'show');
                hold(app.UIAxes2_2, 'off');

                % Update derecruitment_force with calculated forces
                derecruitment_force = cell(numMUs, 2);
                for i = 1:numMUs
                    derecruitment_force{sorted_indices(i), 1} = MU_ID{sorted_indices(i)};
                    derecruitment_force{sorted_indices(i), 2} = mean_derecruitment_forces(sorted_indices(i));
                end
                assignin('base', 'derecruitment_force', derecruitment_force);
            end
            calculateDerecruitmentThresholdCallback(app);  % Call the derecruitment threshold function
            app.StatusTextArea.Value = [app.StatusTextArea.Value; "Calculated Derecruitment Threshold"];
        end

        % Value changed function: AutoCVSwitch
        function AutoCVSwitchValueChanged(app, event)
            value = app.AutoCVSwitch.Value;
            cv_auto = {};
            SIG = evalin('base', 'SIG'); 
            fsamp = evalin('base','fsamp');
            MUAPs = evalin('base','MUAPs');
            MUAPrms = evalin('base', 'MUAPrms');  % Fetch MUAPrms from base workspace;
            MUAPp2p = evalin('base', 'MUAPp2p');  % Fetch MUAPp2p from base workspace
            MU_ID = evalin('base', 'MU_ID');  % Fetch MU_ID from base workspace
            if strcmp(value, 'Calculate CV')
                % Loop through all MUs and calculate CV
                
                for MU_number = 1:length(MU_ID)
                    try
                        % Normalize MUAPp2p and find significant channels
                        MUAPp2p_matrix = cell2mat(MUAPp2p(MU_number));
                        MUAPp2p_normalized = MUAPp2p_matrix / max(MUAPp2p_matrix(:));
                        significantChannels = MUAPp2p_normalized >= 0.2;
        
                        % Calculate polarity matrix
                        polarityMatrix = findChannelPolarity(MUAPs, MU_number);
        
                        % Calculate cross-correlation scores using MUAPs channels of the selected MU
                        crossCorrScores = zeros(size(MUAPs{MU_number}, 1) - 1, size(MUAPs{MU_number}, 2));
                        for r = 1:size(MUAPs{MU_number}, 1) - 1
                            for c = 1:size(MUAPs{MU_number}, 2)
                                if ~isempty(MUAPs{MU_number}{r, c}) && ~isempty(MUAPs{MU_number}{r + 1, c})
                                    [corrVal, ~] = xcorr(mean(MUAPs{MU_number}{r, c}, 1), mean(MUAPs{MU_number}{r + 1, c}, 1), 'coeff');
                                    crossCorrScores(r, c) = max(abs(corrVal));
                                end
                            end
                        end
        
                        % Select columns based on significant channels and cross-correlation scores
                        validColumns = [];
                        numSignificant = sum(significantChannels); % gives the sum of all the row in a single column of a matrix
                        for c = 1:size(MUAPs{MU_number}, 2)
                            if numSignificant(c) >= 3
                                validColumns = [validColumns, c];
                            end
                        end
        
                        % Sort columns by the number of significant channels
                        [~, sortOrder] = sort(numSignificant(validColumns), 'descend');
                        sortedColumns = validColumns(sortOrder);

                        % Find columns and rows for CV analysis based on criteria
                        selectedColumn = [];
                        selectedRows = [];
                        for c = sortedColumns
                            % Find significant rows
                            sigRows = find(significantChannels(:, c));
        
                            % Check cross-correlation and polarity continuation
                            validRows = [];
                            for i = 1:length(sigRows) - 1
                                if crossCorrScores(sigRows(i), c) > 0.7 && strcmp(polarityMatrix(sigRows(i), c), polarityMatrix(sigRows(i + 1), c))
                                    validRows = [validRows, sigRows(i), sigRows(i + 1)];
                                end
                                if length(validRows) >= 3
                                    break;
                                end
                            end
        
                            if length(validRows) >= 3
                                % Ensure all selected rows have the same polarity
                                polarities = polarityMatrix(validRows, c);
                                if all(strcmp(polarities, polarities(1)))
                                    selectedColumn = c;
                                    selectedRows = unique(validRows);
                                    break;
                                end
                            end
                        end
        
                        if isempty(selectedRows)
                            app.StatusTextArea.Value = [app.StatusTextArea.Value; "No suitable rows and columns found for CV analysis."];
                        end
                        % Calculate Conduction Velocity for the selected MU
                        ied = 8.5; % Inter-electrode distance in mm
                        muSig = MUAPs{MU_number};
        
                        % Ensure correct order of rows
                        if length(selectedRows) >= 3
                            signal = muSig(selectedRows, selectedColumn);
                        else
                            app.StatusTextArea.Value = [app.StatusTextArea.Value; "Not enough rows meet the criteria for CV calculation."];
                        end
                        
        
                        % Convert the signal cell to matrix
                        sig = cell2mat(signal);
                        % Estimate initial teta for CV calculation
                        initial_teta = find_mle_teta(sig(2, :), sig(3, :), ied, fsamp);
        
                        % Calculate CV using MLE
                        [cv, teta] = mle_cv_est(sig, initial_teta, ied, fsamp);
        
                        % Store the CV for the selected MU
                        cv_auto{end + 1, 1} = MU_ID{MU_number};
                        cv_auto{end, 2} = abs(cv);
                    catch
                        % In case of any error, store NaN for CV
                        cv_auto{end + 1, 1} = MU_ID{MU_number};
                        cv_auto{end, 2} = NaN;
                    end
                end

                % Display the result
                assignin('base', 'cv_auto', cv_auto);
                app.UITable2.Data = cv_auto;
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "cv_auto Results calculated and stored."];
            else
                % Clear RMS results if switch is off
                app.UITable2.Data = nan;
%                 evalin('base', 'clear cv_auto');
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "cv_auto Results cleared."];
            end

            % Function to find channel polarity
            function polarityMatrix = findChannelPolarity(MUAPs, MU_number)
                % Initialize the polarity matrix with the same size as the MUAP data
                muSig = MUAPs{MU_number};
                [numRows, numCols] = size(muSig);
                polarityMatrix = strings(numRows, numCols);
                
                % Loop through each row and column to determine the polarity
                for row = 1:numRows
                    for col = 1:numCols
                        if ~isempty(muSig{row, col}) && isnumeric(muSig{row, col})
                            signal = muSig{row, col};  
                            [globalMax, maxIdx] = max(signal);
                            [globalMin, minIdx] = min(signal);
                            
                            % Determine polarity based on the timing of global max and min
                            if maxIdx < minIdx
                                polarityMatrix(row, col) = '+';
                            else
                                polarityMatrix(row, col) = '-';
                            end
                        else
                            polarityMatrix(row, col) = 'N/A'; % Handle empty or non-numeric channels
                        end
                    end
                end
            end

            % Function to calculate first and second derivatives for beamforming
            function [de1, de2] = derivatives_beamforming(sig, row, teta)
                total_rows = size(sig, 1);
                m = total_rows - 1;
                total_columns = size(sig, 2);
                half_of_the_columns = (1:round(total_columns/2));
            
                position = zeros(size(sig, 1), 1);
                position(1) = row + 1;
            
                for i = 2:(row+1)
                    position(i) = i - (row + 1);
                end
            
                for i = (row+2):(m+1)
                    position(i) = i - 1;
                end
            
                position = position(2:end);
            
                this_row = sig(row, :);
                sig(row, :) = [];
                sig = [this_row; sig];
            
                sigfft = fft(sig, [], 2);
            
                term_de1 = zeros(size(half_of_the_columns));
                term_de2 = zeros(size(half_of_the_columns));
                term_de12 = zeros(size(half_of_the_columns));
                term_de22 = zeros(size(half_of_the_columns));
            
                for i = 1:m
                    for u = (i+1):m
                        s_fft = sigfft(i+1, half_of_the_columns);
                        s_conj = conj(sigfft(u+1, half_of_the_columns));
                        s_exp = exp(1i * 2 * pi * half_of_the_columns * (position(i) - position(u)) * teta / total_columns);
                        s_last = 2 * pi * half_of_the_columns * (position(i) - position(u)) / total_columns;
                        image = imag(s_fft .* s_conj .* s_exp .* s_last);
                        term_de1 = term_de1 - image;
                    end
                end
            
                term_de1 = (term_de1 * 2) / (m^2);
            
                for i = 1:m
                    s_fft = sigfft(i+1, half_of_the_columns);
                    s_exp = exp(1i * 2 * pi * half_of_the_columns * position(i) * teta / total_columns);
                    s_last = 2 * pi * half_of_the_columns * position(i) / total_columns;
                    term_de2 = term_de2 + (s_fft .* s_exp .* s_last);
                end
            
                s_conj = conj(sigfft(1, half_of_the_columns));
                term_de2 = 2 * imag(s_conj .* term_de2) / m;
            
                de1 = 2 / total_columns * sum(term_de1 + term_de2);
            
                for i = 1:m
                    for u = (i+1):m
                        s_fft = sigfft(i+1, half_of_the_columns);
                        s_conj = conj(sigfft(u+1, half_of_the_columns));
                        s_exp = exp(1i * 2 * pi * half_of_the_columns * (position(i) - position(u)) * teta / total_columns);
                        s_last = 2 * pi * half_of_the_columns * (position(i) - position(u)) / total_columns;
                        term_de12 = term_de12 - real(s_fft .* s_conj .* s_exp .* (s_last.^2));
                    end
                end
            
                term_de12 = (term_de12 * 2) / (m^2);
            
                for i = 1:m
                    s_fft = sigfft(i+1, half_of_the_columns);
                    s_exp = exp(1i * 2 * pi * half_of_the_columns * position(i) * teta / total_columns);
                    s_last = 2 * pi * half_of_the_columns * position(i) / total_columns;
                    term_de22 = term_de22 + (s_fft .* s_exp .* (s_last.^2));
                end
            
                s_conj = conj(sigfft(1, half_of_the_columns));
                term_de22 = 2 * real(s_conj .* term_de22) / m;
            
                de2 = 2 / total_columns * sum(term_de12 + term_de22);
            end


            % Function to find initial teta for CV estimation
            function teta = find_mle_teta(sig1, sig2, ied, fsamp)
                min_cv = 1;
                max_cv = 10;
                teta_min = floor(ied / max_cv * fsamp);
                teta_max = ceil(ied / min_cv * fsamp);
            
                delay = teta_min:teta_max;
                corrpos = zeros(size(delay));
                for idx = 1:length(delay)
                    i = delay(idx);
                    sig1_tosum = sig1(1:end-i);
                    sig2_tosum = sig2((i+1):end);
                    corrpos(idx) = sum(sig1_tosum .* sig2_tosum);
                end
            
                [~, pos] = max(corrpos);
            
                if pos > 1 && pos < length(delay)
                    x = delay(pos-1:pos+1);
                    y = corrpos(pos-1:pos+1);
                    coefs = flip(polyfit(x, y, 2));
                    teta = -coefs(2) / (2 * coefs(1));
                else
                    teta = pos;
                end
            end

            % Function to estimate CV via maximum likelihood estimation
            function [cv, teta] = mle_cv_est(sig, initial_teta, ied, fsamp)
                ied = ied / 1000; 
                t = initial_teta;
                teta = 10;
                trial = 0;
                epsilon = eps;
            
                while abs(teta - t) >= 5e-5 && trial < 30
                    trial = trial + 1;
                    teta = t;
                    de1 = 0;
                    de2 = 0;
            
                    for row = 1:size(sig, 1)
                        [de1t, de2t] = derivatives_beamforming(sig, row, teta);
                        de1 = de1 + de1t + epsilon;
                        de2 = de2 + de2t + epsilon;
                    end
            
                    if de2 > 0
                        u = -de1 / de2;
                        if abs(u) > 0.5
                            u = -0.5 * abs(de1) / de1;
                        end
                    else
                        u = -0.5 * abs(de1) / de1;
                    end
            
                    t = teta + u;
                end
            
                cv = ied / (teta / fsamp);
            end
   
        end

        % Value changed function: SelectedColumnSpinner
        function SelectedColumnSpinnerValueChanged(app, event)
            Selected_Column_Val = round(app.SelectedColumnSpinner.Value);
            app.SelectedColumnSpinner.Value = Selected_Column_Val; 
            
        end

        % Value changed function: StartRowSpinner
        function StartRowSpinnerValueChanged(app, event)
            Start_Row_Val = round(app.StartRowSpinner.Value);
            app.StartRowSpinner.Value = Start_Row_Val;
            
        end

        % Value changed function: EndRowSpinner
        function EndRowSpinnerValueChanged(app, event)
            End_Row_Val = round(pp.EndRowSpinner.Value);
            app.EndRowSpinner.Value = End_Row_Val;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadingDataTab = uitab(app.TabGroup);
            app.LoadingDataTab.Title = 'Loading Data';
            app.MotorGUINeuroMechatronicsLabLabel = uilabel(app.LoadingDataTab);
            app.MotorGUINeuroMechatronicsLabLabel.Text = 'Motor GUI - NeuroMechatronics Lab';
            app.LoadingPanel = uipanel(app.LoadingDataTab);
            app.LoadingPanel.Title = 'Loading';
            app.LoadDataButton = uibutton(app.LoadingPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.StatusTextAreaLabel = uilabel(app.LoadingPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.LoadingPanel);
            app.FilterandExtractPanel = uipanel(app.LoadingDataTab);
            app.FilterandExtractPanel.Title = 'Filter and Extract';
            app.SpatialFilterKnobLabel = uilabel(app.FilterandExtractPanel);
            app.SpatialFilterKnobLabel.Text = 'Spatial Filter';
            app.SpatialFilterKnob = uiknob(app.FilterandExtractPanel, 'discrete');
            app.SpatialFilterKnob.Items = {'Off', 'Default', 'SD Row', 'SD Column', 'Laplacian'};
            app.SpatialFilterKnob.ValueChangedFcn = createCallbackFcn(app, @SpatialFilterKnobValueChanged, true);
            app.ExtractSwitchLabel = uilabel(app.FilterandExtractPanel);
            app.ExtractSwitchLabel.Text = 'Extract';
            app.ExtractSwitch = uiswitch(app.FilterandExtractPanel, 'rocker');
            app.ExtractSwitch.Items = {'Off', 'Extract MUAPs'};
            app.ExtractSwitch.ValueChangedFcn = createCallbackFcn(app, @ExtractSwitchValueChanged, true);
            app.p2pandRMSAnalysisofMUAPTab = uitab(app.TabGroup);
            app.p2pandRMSAnalysisofMUAPTab.Title = 'p2p and RMS Analysis of MUAP';
            app.Maxp2pSwitchLabel = uilabel(app.p2pandRMSAnalysisofMUAPTab);
            app.Maxp2pSwitchLabel.Text = 'Max p2p';
            app.Maxp2pSwitch = uiswitch(app.p2pandRMSAnalysisofMUAPTab, 'slider');
            app.Maxp2pSwitch.ValueChangedFcn = createCallbackFcn(app, @Maxp2pSwitchValueChanged, true);
            app.Avgp2pSwitchLabel = uilabel(app.p2pandRMSAnalysisofMUAPTab);
            app.Avgp2pSwitchLabel.Text = 'Avg p2p';
            app.Avgp2pSwitch = uiswitch(app.p2pandRMSAnalysisofMUAPTab, 'slider');
            app.Avgp2pSwitch.ValueChangedFcn = createCallbackFcn(app, @Avgp2pSwitchValueChanged, true);
            app.minp2pSwitchLabel = uilabel(app.p2pandRMSAnalysisofMUAPTab);
            app.minp2pSwitchLabel.Text = 'min p2p';
            app.minp2pSwitch = uiswitch(app.p2pandRMSAnalysisofMUAPTab, 'slider');
            app.minp2pSwitch.ValueChangedFcn = createCallbackFcn(app, @minp2pSwitchValueChanged, true);
            app.MotorGUINeuroMechatronicsLabLabel_4 = uilabel(app.p2pandRMSAnalysisofMUAPTab);
            app.MotorGUINeuroMechatronicsLabLabel_4.Text = 'Motor GUI - NeuroMechatronics Lab';
            app.MUDropDownLabel = uilabel(app.p2pandRMSAnalysisofMUAPTab);
            app.MUDropDownLabel.Text = 'MU';
            app.MUDropDown = uidropdown(app.p2pandRMSAnalysisofMUAPTab);
            app.MUDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MUDropDownOpening, true);
            app.MUDropDown.ValueChangedFcn = createCallbackFcn(app, @MUDropDownValueChanged, true);
            app.RMSSwitchLabel = uilabel(app.p2pandRMSAnalysisofMUAPTab);
            app.RMSSwitchLabel.Text = 'RMS';
            app.RMSSwitch = uiswitch(app.p2pandRMSAnalysisofMUAPTab, 'slider');
            app.RMSSwitch.ValueChangedFcn = createCallbackFcn(app, @RMSSwitchValueChanged, true);
            app.p2pSwitchLabel = uilabel(app.p2pandRMSAnalysisofMUAPTab);
            app.p2pSwitchLabel.Text = 'p2p';
            app.p2pSwitch = uiswitch(app.p2pandRMSAnalysisofMUAPTab, 'slider');
            app.p2pSwitch.ValueChangedFcn = createCallbackFcn(app, @p2pSwitchValueChanged, true);
            app.AnalysisDropDownLabel = uilabel(app.p2pandRMSAnalysisofMUAPTab);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.p2pandRMSAnalysisofMUAPTab);
            app.AnalysisDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AnalysisDropDownOpening, true);
            app.AnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisDropDownValueChanged, true);
            app.ShowResultButton = uibutton(app.p2pandRMSAnalysisofMUAPTab, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButtonPushed, true);
            app.ShowResultButton.Text = 'Show Result';
            app.UITable = uitable(app.p2pandRMSAnalysisofMUAPTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ThresholdCalculationsTab = uitab(app.TabGroup);
            app.ThresholdCalculationsTab.Title = 'Threshold Calculations';
            app.TabGroup2 = uitabgroup(app.ThresholdCalculationsTab);
            app.RecruitmentTab = uitab(app.TabGroup2);
            app.RecruitmentTab.Title = 'Recruitment';
            app.UIAxes2 = uiaxes(app.RecruitmentTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.NumberofFiringsKnobLabel = uilabel(app.RecruitmentTab);
            app.NumberofFiringsKnobLabel.Text = 'Number of Firings';
            app.NumberofFiringsKnob = uiknob(app.RecruitmentTab, 'continuous');
            app.NumberofFiringsKnob.ValueChangingFcn = createCallbackFcn(app, @NumberofFiringsKnobValueChanging, true);
            app.MotorGUINeuroMechatronicsLabLabel_5 = uilabel(app.RecruitmentTab);
            app.MotorGUINeuroMechatronicsLabLabel_5.Text = 'Motor GUI - NeuroMechatronics Lab';
            app.StartTimesKnobLabel = uilabel(app.RecruitmentTab);
            app.StartTimesKnobLabel.Text = 'Start Time (s)';
            app.StartTimesKnob = uiknob(app.RecruitmentTab, 'continuous');
            app.StartTimesKnob.Limits = [0 200];
            app.StartTimesKnob.ValueChangingFcn = createCallbackFcn(app, @StartTimesKnobValueChanging, true);
            app.EndTimesKnobLabel = uilabel(app.RecruitmentTab);
            app.EndTimesKnobLabel.Text = 'End Time (s)';
            app.EndTimesKnob = uiknob(app.RecruitmentTab, 'continuous');
            app.EndTimesKnob.Limits = [0 200];
            app.EndTimesKnob.ValueChangingFcn = createCallbackFcn(app, @EndTimesKnobValueChanging, true);
            app.EditField_numFiring = uieditfield(app.RecruitmentTab, 'numeric');
            app.EditField_numFiring.ValueChangedFcn = createCallbackFcn(app, @EditField_numFiringValueChanged, true);
            app.EditField_startTime = uieditfield(app.RecruitmentTab, 'numeric');
            app.EditField_startTime.ValueChangedFcn = createCallbackFcn(app, @EditField_startTimeValueChanged, true);
            app.EditField_endTime = uieditfield(app.RecruitmentTab, 'numeric');
            app.EditField_endTime.ValueChangedFcn = createCallbackFcn(app, @EditField_endTimeValueChanged, true);
            app.ShowResultButton_2 = uibutton(app.RecruitmentTab, 'push');
            app.ShowResultButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButton_2Pushed, true);
            app.ShowResultButton_2.Text = 'Show Result';
            app.DerecruitmentTab = uitab(app.TabGroup2);
            app.DerecruitmentTab.Title = 'Derecruitment';
            app.UIAxes2_2 = uiaxes(app.DerecruitmentTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.MotorGUINeuroMechatronicsLabLabel_6 = uilabel(app.DerecruitmentTab);
            app.MotorGUINeuroMechatronicsLabLabel_6.Text = 'Motor GUI - NeuroMechatronics Lab';
            app.EditField_numFiring_2 = uieditfield(app.DerecruitmentTab, 'numeric');
            app.EditField_numFiring_2.ValueChangedFcn = createCallbackFcn(app, @EditField_numFiring_2ValueChanged, true);
            app.EditField_startTime_2 = uieditfield(app.DerecruitmentTab, 'numeric');
            app.EditField_startTime_2.ValueChangedFcn = createCallbackFcn(app, @EditField_startTime_2ValueChanged, true);
            app.EditField_endTime_2 = uieditfield(app.DerecruitmentTab, 'numeric');
            app.EditField_endTime_2.ValueChangedFcn = createCallbackFcn(app, @EditField_endTime_2ValueChanged, true);
            app.NumberofFiringsKnob_2Label = uilabel(app.DerecruitmentTab);
            app.NumberofFiringsKnob_2Label.Text = 'Number of Firings';
            app.NumberofFiringsKnob_2 = uiknob(app.DerecruitmentTab, 'continuous');
            app.NumberofFiringsKnob_2.ValueChangingFcn = createCallbackFcn(app, @NumberofFiringsKnob_2ValueChanging, true);
            app.StartTimesKnob_2Label = uilabel(app.DerecruitmentTab);
            app.StartTimesKnob_2Label.Text = 'Start Time (s)';
            app.StartTimesKnob_2 = uiknob(app.DerecruitmentTab, 'continuous');
            app.StartTimesKnob_2.Limits = [0 200];
            app.StartTimesKnob_2.ValueChangingFcn = createCallbackFcn(app, @StartTimesKnob_2ValueChanging, true);
            app.EndTimesKnob_2Label = uilabel(app.DerecruitmentTab);
            app.EndTimesKnob_2Label.Text = 'End Time (s)';
            app.EndTimesKnob_2 = uiknob(app.DerecruitmentTab, 'continuous');
            app.EndTimesKnob_2.Limits = [0 200];
            app.EndTimesKnob_2.ValueChangingFcn = createCallbackFcn(app, @EndTimesKnob_2ValueChanging, true);
            app.ShowResultButton_3 = uibutton(app.DerecruitmentTab, 'push');
            app.ShowResultButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowResultButton_3Pushed, true);
            app.ShowResultButton_3.Text = 'Show Result';
            app.CVInvestigatorTab = uitab(app.TabGroup);
            app.CVInvestigatorTab.Title = 'CV Investigator';
            app.TabGroup3 = uitabgroup(app.CVInvestigatorTab);
            app.AutoCVTab = uitab(app.TabGroup3);
            app.AutoCVTab.Title = 'Auto CV';
            app.AutoCVSwitch_2Label = uilabel(app.AutoCVTab);
            app.AutoCVSwitch_2Label.Text = 'Auto CV';
            app.AutoCVSwitch = uiswitch(app.AutoCVTab, 'rocker');
            app.AutoCVSwitch.Items = {'Off', 'Calculate CV'};
            app.AutoCVSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoCVSwitchValueChanged, true);
            app.MotorGUINeuroMechatronicsLabLabel_7 = uilabel(app.AutoCVTab);
            app.MotorGUINeuroMechatronicsLabLabel_7.Text = 'Motor GUI - NeuroMechatronics Lab';
            app.UITable2 = uitable(app.AutoCVTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.ManualInvestigtorTab = uitab(app.TabGroup3);
            app.ManualInvestigtorTab.Title = 'Manual Investigtor';
            app.UIAxes3 = uiaxes(app.ManualInvestigtorTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.MotorGUINeuroMechatronicsLabLabel_8 = uilabel(app.ManualInvestigtorTab);
            app.MotorGUINeuroMechatronicsLabLabel_8.Text = 'Motor GUI - NeuroMechatronics Lab';
            app.SelectedColumnLabel = uilabel(app.ManualInvestigtorTab);
            app.SelectedColumnLabel.Text = {'Selected'; 'Column'};
            app.SelectedColumnSpinner = uispinner(app.ManualInvestigtorTab);
            app.SelectedColumnSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectedColumnSpinnerValueChanged, true);
            app.MotorUnitDropDownLabel = uilabel(app.ManualInvestigtorTab);
            app.MotorUnitDropDownLabel.Text = 'Motor Unit';
            app.MotorUnitDropDown = uidropdown(app.ManualInvestigtorTab);
            app.MotorUnitDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MUDropDownOpening, true);
            app.MotorUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @MUDropDownValueChanged, true);
            app.CalculateButton = uibutton(app.ManualInvestigtorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.StartRowSpinnerLabel = uilabel(app.ManualInvestigtorTab);
            app.StartRowSpinnerLabel.Text = {'Start'; 'Row'};
            app.StartRowSpinner = uispinner(app.ManualInvestigtorTab);
            app.StartRowSpinner.ValueChangedFcn = createCallbackFcn(app, @StartRowSpinnerValueChanged, true);
            app.EndRowSpinnerLabel = uilabel(app.ManualInvestigtorTab);
            app.EndRowSpinnerLabel.Text = {'End'; 'Row'};
            app.EndRowSpinner = uispinner(app.ManualInvestigtorTab);
            app.EndRowSpinner.ValueChangedFcn = createCallbackFcn(app, @EndRowSpinnerValueChanged, true);
            app.PlotSignalTab = uitab(app.TabGroup);
            app.PlotSignalTab.Title = 'Plot Signal';
            app.UIAxes = uiaxes(app.PlotSignalTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChooseSignalDropDownLabel = uilabel(app.PlotSignalTab);
            app.ChooseSignalDropDownLabel.Text = 'Choose Signal';
            app.ChooseSignalDropDown = uidropdown(app.PlotSignalTab);
            app.ChooseSignalDropDown.Items = {'Choose option below', 'SIG', 'newSig'};
            app.ChooseSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseSignalDropDownValueChanged, true);
            app.ChooseSignalDropDown.Value = 'Choose option below';
            app.MotorGUINeuroMechatronicsLabLabel_2 = uilabel(app.PlotSignalTab);
            app.MotorGUINeuroMechatronicsLabLabel_2.Text = 'Motor GUI - NeuroMechatronics Lab';
            app.PlotMUAPTab = uitab(app.TabGroup);
            app.PlotMUAPTab.Title = 'Plot MUAP';
            app.UIAxes_2 = uiaxes(app.PlotMUAPTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.MotorGUINeuroMechatronicsLabLabel_3 = uilabel(app.PlotMUAPTab);
            app.MotorGUINeuroMechatronicsLabLabel_3.Text = 'Motor GUI - NeuroMechatronics Lab';
            app.ChooseMUAPDropDownLabel = uilabel(app.PlotMUAPTab);
            app.ChooseMUAPDropDownLabel.Text = 'Choose MUAP';
            app.ChooseMUAPDropDown = uidropdown(app.PlotMUAPTab);
            app.ChooseMUAPDropDown.Items = {'Choose option below', 'MUPulses', 'Force', 'Plot All'};
            app.ChooseMUAPDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseMUAPDropDownValueChanged, true);
            app.ChooseMUAPDropDown.Value = 'Choose option below';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motor_GUI_NML

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end